classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        FxController                   matlab.ui.container.Panel
        Next                           matlab.ui.control.Button
        Previous                       matlab.ui.control.Button
        Save                           matlab.ui.control.Button
        FunctionName                   matlab.ui.control.TextArea
        NameTextAreaLabel              matlab.ui.control.Label
        Degree                         matlab.ui.control.DropDown
        DegreeofpolynomialDropDownLabel  matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        ImportDataButton               matlab.ui.control.Button
        RefreshpointsButton            matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Spinner                        matlab.ui.control.Spinner
        SpinnerLabel                   matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ExportLogButton                matlab.ui.control.Button
        Export                         matlab.ui.container.Panel
        toPDF                          matlab.ui.control.Button
        toOO                           matlab.ui.control.Button
        toEXCEL                        matlab.ui.control.Button
        FunctionList                   matlab.ui.control.TextArea
        SavedfunctionsTextAreaLabel    matlab.ui.control.Label
        FunctionsButtonGroup           matlab.ui.container.ButtonGroup
        All                            matlab.ui.control.RadioButton
        Current                        matlab.ui.control.RadioButton
        Prompt                         matlab.ui.control.TextArea
        PromptTextAreaLabel            matlab.ui.control.Label
        Show                           matlab.ui.container.ButtonGroup
        OnlypointsButton               matlab.ui.control.RadioButton
        OnlyaproximationcurveButton    matlab.ui.control.RadioButton
        AproximationcurvePointsButton  matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

%     TODO List:
%   Add option to delete function
%   Add export options
%   Delete extra functions

% ////////////////////////////////////////////////////////////////////////
% //////////////////////     GLOBAL PROPERITIES     //////////////////////
% ////////////////////////////////////////////////////////////////////////

    properties (Access = public)
        function_database = [];     % Array for saving functions structures
        selected_function = 1;      % Function selection index
        show_option = 'CP';         % Default value for ploting (curve + points)
        global_show_option = 'C';   % Default value for function ploting (show only current function)
        
%         Default values for function structure
        def_function_name = "New function";
        def_function_points_amount = 0;
        def_function_data = {};
        def_function_degree = '1';
    end
    
    methods (Access = private)
% ////////////////////////////////////////////////////////////////////////
% ///////////////////////     PROMPT FUNCTIONS     ///////////////////////
% ////////////////////////////////////////////////////////////////////////
        function ResetPrompt(app)
            set(app.Prompt, 'Value', 'Idle ...')
        end % ResetPrompt function

        function PrintPrompt(app, msg_type, msg)
            switch msg_type
                case 'I'                % INFO
                    set(app.Prompt, ...
                        'Value', convertStringsToChars(append('INFO: ', msg)), ...
                        'FontColor', '#0000ff');
                case 'W'                % WARNING
                    set(app.Prompt, ...
                        'Value', convertStringsToChars(append('WARNING: ', msg)), ...
                        'FontColor', '#ffff00');
                case 'E'                % ERROR
                    set(app.Prompt, ...
                        'Value', convertStringsToChars(append('ERROR: ', msg)), ...
                        'FontColor', '#ff0000');
            end % switch  
        end % PrintPrompt function
        
% ////////////////////////////////////////////////////////////////////////
% ////////////////////////     AXES FUNCTIONS     ////////////////////////
% ////////////////////////////////////////////////////////////////////////

        function ClearAxes(app)
            plot(app.UIAxes, 0, 0);
        end % ClearAxes function
        
% ////////////////////////////////////////////////////////////////////////
% //////////////////////     PLOTING FUNCTIONS     ///////////////////////
% ////////////////////////////////////////////////////////////////////////
        
        function PlotCurrentFunction(app)
%             Convert table to matrix   (cell check DIN)
            if (class(app.UITable.Data) ~= 'cell') %#ok<BDSCA> 
                PrintPromtp(app, 'E', "Unrecognized data type of data structure.");
                return;
            else
                matrix = cell2mat(app.UITable.Data);
            end % if
%             Get polynomial degree and check if it's numeric data type
            polynomial_approximation_degree = GetDegree(app, get(app.Degree, 'Value'));
%             DIN
            if (~(isnumeric(polynomial_approximation_degree)))
                PrintPrompt(app, 'W', "Polynomial approximation degree is not numeric, setting default value (1)");
                polynomial_approximation_degree = 1;
            end  
%             Get regression coefficients
            polynomial_approximation_coefficients = polyfit(matrix(:,1), matrix(:,2), polynomial_approximation_degree);                                 
%             function = points, function1 = approximation curve
            x = matrix(:,1);
            y = matrix(:,2);
            x1 = min(matrix(:,1)) - 1 : 0.1 : max(matrix(:,1)) + 1;
            y1 = polyval(polynomial_approximation_coefficients, x1);
%             Plot data
            switch app.show_option
                case 'CP'
                    plot(app.UIAxes, x1, y1, x, y, 'o');
                case 'C'
                    plot(app.UIAxes, x1, y1);
                case 'P'
                    plot(app.UIAxes, x, y, 'o');
             end % switch
             set(app.UIAxes.Title, 'String', string(app.FunctionName.Value));
        end % PlotCurrentFunction

        function PlotData(app)
% TODO: Popup msgbox to set name for all functions graph

            switch app.global_show_option
                case 'C'
                    PlotCurrentFunction(app);
                case 'A'
%                     Plot current function
                    PlotCurrentFunction(app);
%                     Plot functions from database
                    for i = 1:size(app.function_database, 2)
                        hold(app.UIAxes, "on");
                        matrix = cell2mat(num2cell(app.function_database(i).Data));
                        polynomial_approximation_degree = GetDegree(app, app.function_database(i).Degree);
                        polynomial_aproximation_coefficients = polyfit(matrix(:,1), matrix(:,2), polynomial_approximation_degree);
                        x = matrix(:,1);
                        y = matrix(:,2);
                        x1 = min(matrix(:,1)) - 1 : 0.1 : max(matrix(:,1)) + 1;
                        y1 = polyval(polynomial_aproximation_coefficients, x1);
                        switch app.show_option
                            case 'CP'
                                plot(app.UIAxes, x1, y1, x, y, 'o');
                            case 'C'
                                plot(app.UIAxes, x1, y1);
                            case 'P'
                                plot(app.UIAxes, x, y, 'o');
                        end % switch
                        hold(app.UIAxes, "off");
                    end % for
            end % switch
        end % PlotData

% ////////////////////////////////////////////////////////////////////////
% ///////////////////////     DATA CONTROLLER     ////////////////////////
% ////////////////////////////////////////////////////////////////////////
                
        function degree = GetDegree(app, char_value)
            switch char_value
                case '1'
                    degree = 1;
                case '2'
                    degree = 2;
                case '3'
                    degree = 3;
                case '4'
                    degree = 4;
                case '5'
                    degree = 5;
                case 'n-1'
                    degree = app.Spinner.Value - 1;
            end % switch
        end % GetDegree function
        
% ////////////////////////////////////////////////////////////////////////
% //////////////////////     DATABASE FUNCTIONS     //////////////////////
% ////////////////////////////////////////////////////////////////////////

        function RefreshFunctionList (app)
            if (isempty(app.function_database))
                return;
            end % if
            names_array = char(app.function_database.Name);
            new_array = [];
            for i = 1:size(names_array, 1) 
                old_array = [new_array, names_array(i,:)];
                new_array = [old_array, newline];
            end % for
            set(app.FunctionList, 'Value', new_array);
        end % RefreshFunctionList
    end % methods

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
%             Get variables value
            point_amount = app.Spinner.Value;               % Current spinner value
            previous_point_amount = event.PreviousValue;    % Spinner value before change   

%             Increment
            if (point_amount > previous_point_amount) 
                default_values = {0, 0};
                old_data = get(app.UITable, 'data');    % Save table
                new_data = [old_data;default_values];   % New coordinates = previous coordinates & default coordinates
                set(app.UITable, 'data', new_data);     % Add new coordinates to table

%              Decrement
            elseif (point_amount < previous_point_amount)
                app.UITable.Data((previous_point_amount), :) = [];  % Delete last row from table

%              Unexpected state
            else
                PrintPrompt(app, 'E', "Unexpected state of poins amount spinner.")
                return;
            end % if
        end

        % Button pushed function: RefreshpointsButton
        function RefreshpointsButtonPushed(app, event)
%             Reset prompt
            ResetPrompt(app);
            ClearAxes(app);
            RefreshFunctionList(app);
            
%             Check piont amount spinner
            if (get(app.Spinner, 'Value') == 0)     % Value is set to zero - no data
                PrintPrompt(app, 'E', "Point amount is set to zero, no data to display.");
                return;
            elseif (get(app.Spinner, 'Value') < 0) % Value is less than zero
                PrintPrompt(app, 'E', "Point amount cannot be negative number.");
                return;
            end % if

            PlotData(app);
        end

        % Selection changed function: Show
        function ShowSelectionChanged(app, event)
%             Plot only points
            if (get(app.OnlypointsButton, 'Value') == true)
                app.show_option = 'P';
%             Plot only curve
            elseif (get(app.OnlyaproximationcurveButton, 'Value') == true)
                app.show_option = 'C';
%             Plot points & curve
            elseif (get(app.AproximationcurvePointsButton, 'Value') == true)
                app.show_option = 'CP';
            else
                PrintPrompt(app, 'E', "Cannot get value from show options.");
                return;
            end % if
            RefreshpointsButtonPushed(app, event);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
% TODO: Add support for older version of excel files and for openoffice
% files

%             Select .xlsx file
            [file_name, file_path] = uigetfile({'*.xlsx'}, 'Select a file', 'C:\Documents\');
            if (isequal(file_name, 0))
                PrintPrompt(app, 'I', "User did not selected file, no data imported.")
                return;
            elseif (isequal(file_path, 0))
                PrintPrompt(app, 'E', "Failed getting file path, no data imported.");
                return;
            end % if
            figure(app.UIFigure); % Reopen window after it's minimalised

%             Set 'file' as string where is stored full path name of file
            file = fullfile(file_path, file_name);

%             Read file
            file_data = readtable(file);
            
%             Copy data to app table
            vars = {'X'; 'Y'};
            file_data = file_data(:, vars);
            set(app.UITable, 'Data', file_data);

%             Convert from table to cell array and import to UITable
            table = app.UITable.Data;
            cell_array = table2cell(table);
            set(app.UITable, 'Data', cell_array);

%             Set spinner value according to imported data and set points
%             amount spinner
            matrix = cell2mat(cell_array);
            set(app.Spinner, 'Value', size(matrix, 1));

%             Send data to ploting function
            RefreshpointsButtonPushed(app,event);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
%             Set default value for table data
            app.UITable.Data = app.def_function_data;
%             Clear all from app axes
            ClearAxes(app);
        end

        % Value changed function: Degree
        function DegreeValueChanged(app, event)
            RefreshpointsButtonPushed(app, event);
        end

        % Button pushed function: Save
        function SavePushed(app, event)
%             Check if function is not empty
            if (app.Spinner.Value <= 0)
                PrintPrompt( app, 'E', "Function saving failed. Cannot save empty function.");
            end

%             Create function structure
            function_structure = struct( ...
                'ID', app.selected_function, ...
                'Name', char(app.FunctionName.Value), ...
                'Points_Amount', app.Spinner.Value,...
                'Data', cell2mat(app.UITable.Data), ...
                'Degree', app.Degree.Value);

%             Save function to database
%             Function is new
            if (size(app.function_database) < function_structure.ID)
                app.function_database = [app.function_database, function_structure];
                PrintPrompt(app, 'I', "Function saved succefully.");
                RefreshFunctionList(app);

%             Function already exists - overwrite
            elseif (app.function_database(function_structure.ID).ID == function_structure.ID)
                app.function_database(function_structure.ID) = function_structure;
                PrintPrompt(app, 'I', "Function overwrited succefully.");
                RefreshFunctionList(app);

%             Unexpected state
            else
                PrintPrompt(app, 'E', "Saving function failed.");
                return;
            end % if
            set(app.Next, 'Enable', true);
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
            new_function = true;
            set(app.Previous, 'Enable', true);

%             Increment function selection index
            app.selected_function = app.selected_function + 1;

%             Set default values
            set(app.FunctionName, 'Value', app.def_function_name);
            set(app.Spinner, 'Value', app.def_function_points_amount);
            set(app.UITable, 'Data', app.def_function_data);
            set(app.Degree, 'Value', app.def_function_degree);
            ClearAxes(app);

%             If function already exists - load from database
            for i = 1 : size(app.function_database, 2)
                if (app.function_database(i).ID == app.selected_function)
                    set(app.FunctionName, 'Value', app.function_database(i).Name);
                    set(app.Spinner, 'Value', app.function_database(i).Points_Amount);
                    set(app.UITable, 'Data', num2cell(app.function_database(i).Data));
                    set(app.Degree, 'Value', app.function_database(i).Degree);
                    RefreshpointsButtonPushed(app, event);
                    return;
                end
            end

            if (new_function == true)
                set(app.Next, 'Enable', false);
            end

        end

        % Button pushed function: Previous
        function PreviousButtonPushed(app, event)
            if (app.selected_function == 2)
                set(app.Previous, 'Enable', false);           
            end
            app.selected_function = app.selected_function - 1;
            for i = 1 : size(app.function_database, 2)
                if (app.function_database(i).ID == app.selected_function)
                    set(app.FunctionName, 'Value', app.function_database(i).Name);
                    set(app.Spinner, 'Value', app.function_database(i).Points_Amount);
                    set(app.UITable, 'Data', num2cell(app.function_database(i).Data));
                    set(app.Degree, 'Value', app.function_database(i).Degree);
                    RefreshpointsButtonPushed(app,event);
                    set(app.Next, 'Enable', true);
                    return;
                end
            end
        end

        % Selection changed function: FunctionsButtonGroup
        function FunctionsButtonGroupSelectionChanged(app, event)
            if (get(app.All, 'Value') == true)
                app.global_show_option = 'A';
            elseif (get(app.Current, 'Value') == true)
                app.global_show_option = 'C';
            else 
                % Internal app errror
            end
            RefreshpointsButtonPushed(app, event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {601, 601};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SpinnerLabel = uilabel(app.LeftPanel);
            app.SpinnerLabel.Text = 'Počet bodů';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.Limits = [0 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Editable = 'off';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.RefreshpointsButton = uibutton(app.LeftPanel, 'push');
            app.RefreshpointsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpointsButtonPushed, true);
            app.RefreshpointsButton.Text = 'Refresh points';
            app.ImportDataButton = uibutton(app.LeftPanel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DegreeofpolynomialDropDownLabel = uilabel(app.LeftPanel);
            app.DegreeofpolynomialDropDownLabel.Text = 'Degree of polynomial';
            app.Degree = uidropdown(app.LeftPanel);
            app.Degree.Items = {'1', '2', '5', 'exp'};
            app.Degree.ValueChangedFcn = createCallbackFcn(app, @DegreeValueChanged, true);
            app.Degree.Value = '1';
            app.FxController = uipanel(app.LeftPanel);
            app.FxController.Title = 'Function controller';
            app.NameTextAreaLabel = uilabel(app.FxController);
            app.NameTextAreaLabel.Text = 'Name:';
            app.FunctionName = uitextarea(app.FxController);
            app.FunctionName.Placeholder = 'Enter function name';
            app.FunctionName.Value = {'Function 1'};
            app.Save = uibutton(app.FxController, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SavePushed, true);
            app.Save.Text = 'Save function';
            app.Previous = uibutton(app.FxController, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.Previous.Enable = 'off';
            app.Previous.Text = 'Previous';
            app.Next = uibutton(app.FxController, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Enable = 'off';
            app.Next.Text = 'Next';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Show = uibuttongroup(app.RightPanel);
            app.Show.SelectionChangedFcn = createCallbackFcn(app, @ShowSelectionChanged, true);
            app.Show.Title = 'Show';
            app.AproximationcurvePointsButton = uiradiobutton(app.Show);
            app.AproximationcurvePointsButton.Text = 'Aproximation curve + Points';
            app.AproximationcurvePointsButton.Value = true;
            app.OnlyaproximationcurveButton = uiradiobutton(app.Show);
            app.OnlyaproximationcurveButton.Text = 'Only aproximation curve';
            app.OnlypointsButton = uiradiobutton(app.Show);
            app.OnlypointsButton.Text = 'Only points';
            app.PromptTextAreaLabel = uilabel(app.RightPanel);
            app.PromptTextAreaLabel.Text = 'Prompt';
            app.Prompt = uitextarea(app.RightPanel);
            app.Prompt.Editable = 'off';
            app.Prompt.Value = {'Idle ...'};
            app.FunctionsButtonGroup = uibuttongroup(app.RightPanel);
            app.FunctionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FunctionsButtonGroupSelectionChanged, true);
            app.FunctionsButtonGroup.Title = 'Functions';
            app.Current = uiradiobutton(app.FunctionsButtonGroup);
            app.Current.Text = 'Only current function';
            app.Current.Value = true;
            app.All = uiradiobutton(app.FunctionsButtonGroup);
            app.All.Text = 'All functions';
            app.SavedfunctionsTextAreaLabel = uilabel(app.RightPanel);
            app.SavedfunctionsTextAreaLabel.Text = 'Saved functions';
            app.FunctionList = uitextarea(app.RightPanel);
            app.FunctionList.Editable = 'off';
            app.FunctionList.Placeholder = 'No function was saved';
            app.Export = uipanel(app.RightPanel);
            app.Export.Title = 'Export';
            app.toEXCEL = uibutton(app.Export, 'push');
            app.toEXCEL.Text = 'Excel';
            app.toOO = uibutton(app.Export, 'push');
            app.toOO.Text = 'OpenOffice';
            app.toPDF = uibutton(app.Export, 'push');
            app.toPDF.Text = 'PDF';
            app.ExportLogButton = uibutton(app.RightPanel, 'push');
            app.ExportLogButton.Text = 'Open log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end